classdef column_arrangement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DistSpinner           matlab.ui.control.Spinner
        RotaionLabel_4        matlab.ui.control.Label
        DistSlider            matlab.ui.control.Slider
        XwgtSpinner           matlab.ui.control.Spinner
        RotaionLabel_3        matlab.ui.control.Label
        XwgtSlider            matlab.ui.control.Slider
        DenSpinner            matlab.ui.control.Spinner
        RotaionLabel_2        matlab.ui.control.Label
        DenSlider             matlab.ui.control.Slider
        ROInote               matlab.ui.control.Label
        ROIset                matlab.ui.control.Button
        RotSlider             matlab.ui.control.Slider
        RotSpinner            matlab.ui.control.Spinner
        RotaionLabel          matlab.ui.control.Label
        ProLabel              matlab.ui.control.Label
        Export                matlab.ui.control.Button
        CSpinner              matlab.ui.control.Spinner
        ChannelLabel          matlab.ui.control.Label
        CSlider               matlab.ui.control.Slider
        ZbottomSpinner        matlab.ui.control.Spinner
        ZbottomSpinnerLabel   matlab.ui.control.Label
        DetLabel              matlab.ui.control.Label
        ZtopSpinner           matlab.ui.control.Spinner
        ZtopSpinnerLabel      matlab.ui.control.Label
        ZbottomLabel          matlab.ui.control.Label
        ZtopLabel             matlab.ui.control.Label
        ZbottomSlider         matlab.ui.control.Slider
        ZtopSlider            matlab.ui.control.Slider
        LSMfileDropDown       matlab.ui.control.DropDown
        LSMfileDropDownLabel  matlab.ui.control.Label
        DetImage              matlab.ui.control.UIAxes
        ProImage              matlab.ui.control.UIAxes
        ZtopImage             matlab.ui.control.UIAxes
        ZbottomImage          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Xmax=512;
        Ymax=512;
        Cmax=1;
        Tmax=1;
        Zmax=1;
        Ztop=1;
        Zbottom=1;
        Xwgt=0.5;
        Dist=15;
        Hex=0;%ROIが確定しているかどうか
        ROI=zeros(6,2);%ROIの座標
        ROIflag=0;%ROI指定中かどうか
        ROIcount=0;%確定したROIの頂点の数
        Rot=0;
        RotMax=30;
        Denoise=2;
        T=1;
        C=1;
        Offset=0;%for Airyscan
        Bit=8;
        ori
        img
        imgRot
        imgExpOri
        imgExpOve
        imgpath
        Subtraction=0;
        R1
        Mean
        Std
        List
    end
    
    methods (Access = private)
        
        function Projection(app)
            %Topの画像 imgT
            I=app.C+(app.Ztop-1)*app.Cmax;
            imgT=double(app.ori{1}{I,1})-app.Offset;
            imagesc(app.ZtopImage,imgT);

            %Bottomの画像 imgB
            I=app.C+(app.Zbottom-1)*app.Cmax;
            imgB=double(app.ori{1}{I,1})-app.Offset;
            imagesc(app.ZbottomImage,imgB);
    
            %Projection画像 imgM
            imgM=imgT;
            if app.Zmax>2
                for Z=app.Ztop+1:app.Zbottom-1
                    I=app.C + (Z-1)*app.Cmax;
                    img=double(app.ori{1}{I,1})-app.Offset;
                    imgM=(imgM<img).*img+(imgM>=img).*imgM;
                end
            end
            imgM=(imgM<imgB).*imgB+(imgM>=imgB).*imgM;
            imgR=imrotate(imgM,app.Rot,'crop');

            imgC=zeros(app.Ymax,app.Xmax,3);
            if app.Bit==8
               imgM=uint8(imgM);imgR=uint8(imgR);imgC=uint8(imgC);
            elseif app.Bit==16
               imgM=uint16(imgM);imgR=uint16(imgR);imgC=uint16(imgC);
            end
            app.img=imgM;app.imgRot=imgR;
           
            imgC(:,:,1)=imgR;imgC(:,:,2)=imgR;imgC(:,:,3)=imgR;
            Grid=(2^app.Bit-1)/2;
            imgC(round(app.Ymax/2),:,1)=Grid;imgC(round(app.Ymax/4),:,1)=Grid;imgC(round(app.Ymax*3/4),:,1)=Grid;
            imgC(:,round(app.Xmax/2),1)=Grid;
            imagesc(app.ProImage,imgC,'HitTest','off','PickableParts','all');xlim([1 app.Xmax]);ylim([1 app.Ymax]);
            app.ProImage.Toolbar.Visible='off';
            if app.Hex==1
                hold on;
                patch(app.ProImage,app.ROI(:,1),app.ROI(:,2),'r','EdgeColor','r','FaceAlpha',0.3);
                hold off;
                Detection(app);
            elseif app.ROIflag==1
                hold on;
                patch(app.ProImage,app.ROI(1:app.ROIcount,1),app.ROI(1:app.ROIcount,2),'r','EdgeColor','r','FaceAlpha',0.3);
                hold off;
            end
        end

        function Detection(app)
            XX=app.ROI(:,1);YY=app.ROI(:,2);
            width=max(XX)-min(XX)+1;height=max(YY)-min(YY)+1;
            %H=polyshape(app.ROI(:,1),app.ROI(:,2));
            H=polyshape(XX-min(XX)+1,YY-min(YY)+1);

            imgCrop=imcrop(app.img,[min(XX) min(YY) width height]);

            level=graythresh(imgCrop);%level=graythresh(app.img);
            bw=imbinarize(imgCrop,level);
            nhood=[0 1 0; 1 1 1; 0 1 0];
            if app.Denoise>0
                for I=1:app.Denoise
                    bw=imerode(bw,nhood);
                end
                for I=1:app.Denoise
                    bw=imdilate(bw,nhood);
                end
            end

            Overlay=labeloverlay(imgCrop,bw);%Overlay=labeloverlay(app.img,bw);
            Overlay=imrotate(Overlay,app.Rot,'crop');
            app.DetImage.NextPlot="replace";

            imagesc(app.DetImage,Overlay,'HitTest','off','PickableParts','all');
            %xlim([min(1) width]);ylim([min(1) height]);
            app.DetImage.DataAspectRatio = [1 1 1];
            app.DetImage.PlotBoxAspectRatio = [width height 1];
            app.DetImage.XTick = [];
            app.DetImage.YTick = [];
            
            stat=regionprops(bw,'basic');
            N=numel(stat);J=1;
            Olist=zeros(1,4);%1,2:回転後のXY座標, 3,4:回転前のXY座標
            %X0=round((app.Xmax-1)/2);Y0=round((app.Ymax-1)/2);
            X0=round((width-1)/2);Y0=round((height-1)/2);
            Theta=app.Rot/180*pi;
            if N>0
                %ROIに含まれた座標以外は削除
                for I=1:N
                    X=stat(I).Centroid;
                    XR=X(1)-X0;YR=X(2)-Y0;
                    X(1)=cos(Theta)*XR+sin(Theta)*YR+X0;
                    X(2)=-sin(Theta)*XR+cos(Theta)*YR+Y0;
                    if (isinterior(H,X(1),X(2))==1)
                        Olist(J,1:2)=X';
                        Olist(J,3)=XR+X0;
                        Olist(J,4)=YR+Y0;
                        Olist(J,5)=stat(I).Area;
                        J=J+1;
                    end
                end
                N=J-1;
            end
            hold(app.DetImage,'on');
            plot(app.DetImage,Olist(:,1),Olist(:,2),'w*');
            text(app.DetImage,Olist(:,1),Olist(:,2),num2str(Olist(:,5)),'Color','w');
            xlim([min(XX) max(XX)]);ylim([min(YY) max(YY)]);
            hold off;

            % %グループ解析（うまく行かない）
            % Flist=Olist;Flist(:,5)=0;Final=cell(1,1);
            % Group=1;candidate=zeros(1,1);%groupを1とする
            % while size(Flist,1)~=0   
            %     Gnum=1;Glist=zeros(1,5);%1,2:回転後のXY座標, 3,4:回転前のXY座標, 5:Group
            %     Flist(1,5)=Group;
            %     Glist(Gnum,:)=Flist(1,:);Flist(1,:)=[];%Flistの一番上をGlistに退避
            %     X=Glist(Gnum,1);Y=Glist(Gnum,2);
            %     while size(Flist,1)~=0
            %         V=(X-Flist(:,1))*app.Xwgt;
            %         W=Y-Flist(:,2);
            %         R=sqrt(V.^2+W.^2);
            %         for I=1:size(Flist,1)
            %             if R(I)<app.Dist
            %                 Flist(I,5)=Group;%近くにあるシナプスにgroupを設定
            %             end
            %         end
            %         candidate=find(Flist(:,5)==Group);%groupが設定されたシナプスのリスト
            %         if size(candidate,1)==0 %groupが設定されたシナプスがなければ中止
            %             break
            %         end
            %         Gnum=Gnum+1;
            % 
            %         Glist(Gnum,:)=Flist(candidate(1),:);Glist(Gnum,5)=Group;%１番目のシナプスをGlistに退避
            %         Flist(candidate(1),:)=[];
            %         X=Glist(Gnum,1);Y=Glist(Gnum,2);%退避したシナプスの座標X,Y
            %     end    
            %     Final{Group}=Glist;
            %     Group=Group+1;
            % end
            % 
            % Mark=["*r" "*g" "*c" "*m" "*y" "*w" "+r" "+g" "+c" "+m" "+y" "+w" "vr" "vg" "vc" "vm" "vy" "vw"];
            % Group=size(Final,2);
            % for I=1:Group
            %     Slist=Final{I}(:,1:4)
            %     hold(app.DetImage,'on');
            %     plot(app.DetImage,Slist(:,1),Slist(:,2),Mark(I));
            %     hold off;
            % end

            %各点から一番近い点までの距離を計算
            Nlist=zeros(N,2);
            for I=1:N
                X=Olist(I,3);Y=Olist(I,4);
                V=X-Olist(:,3);W=Y-Olist(:,4);
                R=sqrt(V.^2+W.^2);
                R(R==0)=[];
                R=sort(R,'ascend');
                if size(R,2)~=0
                    Nlist(I,1)=R(1);%１番近い距離
                    Nlist(I,2)=R(2);%２番目
                    Olist(I,6)=Nlist(I,1);
                end
            end
            app.R1=Nlist(:,1);
            app.Mean=mean(app.R1);
            app.Std=std(app.R1);
            app.List=Olist;
            app.imgExpOri=imrotate(imgCrop,app.Rot,'crop');
            app.imgExpOve=Overlay; 
            app.Export.Enable="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'onscreen');

            %filelist=[dir('LSMdata/*.czi'), dir('LSMdata/*.lsm')];
            filelist=dir('LSMdata/*.czi');
            set(app.LSMfileDropDown,'Items',{filelist.name});
            app.imgpath = fullfile('LSMdata',get(app.LSMfileDropDown,'Value'));  

            app.ori=bfopen(app.imgpath);
            info=app.ori{1}{1,2};
            app.Xmax=size(app.ori{1}{1,1},1);app.Ymax=size(app.ori{1}{1,1},2);
            app.Cmax=str2num(extractAfter(extractAfter(info,"C="),"/"));

            hash=app.ori{2};
            app.Bit=str2num(hash.get('Global Experiment|AcquisitionBlock|AcquisitionModeSetup|BitsPerSample #1'));
            AS=hash.get('Global Experiment|AcquisitionBlock|MultiTrackSetup|TrackSetup|Detector|AiryScanMode #1');

            if isempty(extract(AS,'SuperResolution'))
                app.Offset=0;
            else
                app.Offset=10000;
            end

            if isempty(extractAfter(info,"T="))
                app.Tmax=1;
            else
                app.Tmax=str2num(extractAfter(extractAfter(info,"T="),"/"));
            end

            if isempty(extractAfter(info,"C="))
                app.Cmax=1;
            else
                if isempty(extractBefore(extractAfter(info,"C="),";"))
                    app.Cmax=str2num(extractAfter(extractAfter(info,"C="),"/"));
                else
                    app.Cmax=str2num(extractAfter(extractBefore(extractAfter(info,"C="),";"),"/"));
                end  
            end
                
            if isempty(extractAfter(info,"Z="))
                app.Zmax=1;
            else
                if isempty(extractBefore(extractAfter(info,"Z="),";"))
                    app.Zmax=str2num(extractAfter(extractAfter(info,"Z="),"/"));
                else
                    app.Zmax=str2num(extractAfter(extractBefore(extractAfter(info,"Z="),";"),"/"));
                end  
            end
            app.T=1;app.Rot=0;app.Hex=0;app.ROI=zeros(6,2);
            app.Ztop=6;app.Zbottom=7;%app.Zmax;
            app.CSlider.Limits=[1 app.Cmax+0.1];
            app.CSpinner.Limits=[1 app.Cmax+0.1];app.CSpinner.Value=app.C;

            app.ZtopSlider.Limits=[1 app.Zmax];app.ZtopSlider.Value=app.Ztop;
            app.ZtopSpinner.Limits=[1 app.Zmax];app.ZtopSpinner.Value=app.Ztop;
            app.ZbottomSlider.Limits=[1 app.Zmax];app.ZbottomSlider.Value=app.Zbottom;
            app.ZbottomSpinner.Limits=[1 app.Zmax];app.ZbottomSpinner.Value=app.Zbottom;

            app.RotSlider.Limits=[-app.RotMax app.RotMax];app.RotSlider.Value=app.Rot;
            app.RotSpinner.Limits=[-app.RotMax app.RotMax];app.RotSpinner.Value=app.Rot;

            app.DenSlider.Limits=[0 5];app.DenSlider.Value=app.Denoise;
            app.DenSpinner.Limits=[0 5];app.DenSpinner.Value=app.Denoise;

            app.XwgtSlider.Limits=[0 1];app.XwgtSlider.Value=app.Xwgt;
            app.XwgtSpinner.Limits=[0 1];app.XwgtSpinner.Value=app.Xwgt;

            app.DistSlider.Limits=[5 30];app.DistSlider.Value=app.Dist;
            app.DistSpinner.Limits=[5 30];app.DistSpinner.Value=app.Dist;

            app.ROInote.Text=strcat('Click ROIset to start specifying ROI');

            Projection(app);
            app.Export.Enable="off";
        end

        % Value changed function: ZtopSlider
        function ZtopSliderValueChanged(app, event)
            app.Ztop = round(app.ZtopSlider.Value);
            if app.Ztop > app.Zbottom
                app.Ztop = app.Zbottom;
            end
            app.ZtopSlider.Value=app.Ztop;
            app.ZtopSpinner.Value=app.Ztop ;

            Projection(app);
        end

        % Value changed function: ZbottomSlider
        function ZbottomSliderValueChanged(app, event)
            app.Zbottom = round(app.ZbottomSlider.Value);
            if app.Ztop > app.Zbottom
                app.Zbottom = app.Ztop;
            end
            app.ZbottomSlider.Value=app.Zbottom;
            app.ZbottomSpinner.Value=app.Zbottom;

            Projection(app);
        end

        % Value changed function: ZtopSpinner
        function ZtopSpinnerValueChanged(app, event)
            app.Ztop = round(app.ZtopSpinner.Value);
            if app.Ztop > app.Zbottom
                app.Ztop = app.Zbottom;
            end
            app.ZtopSlider.Value=app.Ztop;
            app.ZtopSpinner.Value=app.Ztop ;

            Projection(app);
            
        end

        % Value changed function: ZbottomSpinner
        function ZbottomSpinnerValueChanged(app, event)
            app.Zbottom = round(app.ZbottomSpinner.Value);
            if app.Ztop > app.Zbottom
                app.Zbottom = app.Ztop;
            end
            app.ZbottomSlider.Value=app.Zbottom;
            app.ZbottomSpinner.Value=app.Zbottom;

            Projection(app);
            
        end

        % Value changed function: LSMfileDropDown
        function LSMfileDropDownValueChanged(app, event)

            filelist=[dir('LSMdata/*.czi'), dir('LSMdata/*.lsm')];
            set(app.LSMfileDropDown,'Items',{filelist.name});
            app.imgpath = fullfile('LSMdata',get(app.LSMfileDropDown,'Value'));
            
            app.ori=bfopen(app.imgpath);
            info=app.ori{1}{1,2};
            app.Xmax=size(app.ori{1}{1,1},2);
            app.Ymax=size(app.ori{1}{1,1},1);
            app.Cmax=str2num(extractAfter(extractAfter(info,"C="),"/"));

            hash=app.ori{2};
            app.Bit=str2num(hash.get('Global Experiment|AcquisitionBlock|AcquisitionModeSetup|BitsPerSample #1'));
            AS=hash.get('Global Experiment|AcquisitionBlock|MultiTrackSetup|TrackSetup|Detector|AiryScanMode #1');

            if isempty(extract(AS,'SuperResolution'))
                app.Offset=0;
            else
                app.Offset=10000;
            end

            if isempty(extractAfter(info,"T="))
                app.Tmax=1;
            else
                app.Tmax=str2num(extractAfter(extractAfter(info,"T="),"/"));
            end

            if isempty(extractAfter(info,"C="))
                app.Cmax=1;
            else
                if isempty(extractBefore(extractAfter(info,"C="),";"))
                    app.Cmax=str2num(extractAfter(extractAfter(info,"C="),"/"));
                else
                    app.Cmax=str2num(extractAfter(extractBefore(extractAfter(info,"C="),";"),"/"));
                end  
            end
                
            if isempty(extractAfter(info,"Z="))
                app.Zmax=1;
            else
                if isempty(extractBefore(extractAfter(info,"Z="),";"))
                    app.Zmax=str2num(extractAfter(extractAfter(info,"Z="),"/"));
                else
                    app.Zmax=str2num(extractAfter(extractBefore(extractAfter(info,"Z="),";"),"/"));
                end  
            end
            app.T=1;
            app.Ztop=1;
            app.Zbottom=app.Zmax;
            app.CSlider.Limits=[1 app.Cmax+0.1];
            app.CSpinner.Limits=[1 app.Cmax+0.1];app.CSpinner.Value=app.C;

            app.ZtopSlider.Limits=[1 app.Zmax+0.1];app.ZtopSlider.Value=app.Ztop;
            app.ZtopSpinner.Limits=[1 app.Zmax+0.1];app.ZtopSpinner.Value=app.Ztop;
            app.ZbottomSlider.Limits=[1 app.Zmax+0.1];app.ZbottomSlider.Value=app.Zbottom;
            app.ZbottomSpinner.Limits=[1 app.Zmax+0.1];app.ZbottomSpinner.Value=app.Zbottom;

            Projection(app);  
            app.Export.Enable="off";
        end

        % Value changed function: CSlider
        function CSliderValueChanged(app, event)
            app.C = round(app.CSlider.Value);
            app.CSlider.Value=app.C;
            app.CSpinner.Value=app.C;

            Projection(app);
        end

        % Value changed function: CSpinner
        function CSpinnerValueChanged(app, event)
            app.C = round(app.CSpinner.Value);
            app.CSlider.Value=app.C;
            app.CSpinner.Value=app.C;

            Projection(app);
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            [a name c]=fileparts(app.imgpath);
            TIFfilename1=strcat(name,'_',char(datetime('now','Format','MMdd_HHmm')),'.tif');
            TIFfilename2=strcat(name,'_',char(datetime('now','Format','MMdd_HHmm')),'O.tif');
            EXCfilename=strcat(name,'_',char(datetime('now','Format','MMdd_HHmm')),'.xlsx');
            cd('LSMdata');

            Excl=app.List;
            N=size(Excl,1);
            Excl(1,7)=app.Mean;
            Excl(1,8)=app.Std;
            Text={"Xrot" "Yrot" "Xori" "Yori" "Area" "R1" "R1mean" "R1std"};
            writecell(Text,EXCfilename,'Range','A1');
            writematrix(Excl,EXCfilename,'Range','A2');
            imwrite(app.imgExpOri,TIFfilename1);
            imwrite(app.imgExpOve,TIFfilename2);

            cd ..
        end

        % Value changed function: RotSlider
        function RotSliderValueChanged(app, event)
            app.Rot = round(app.RotSlider.Value);
            if app.Rot > app.RotMax
                app.Rot = app.RotMax;
            end
            if app.Rot < -app.RotMax
                app.Rot = -app.RotMax;
            end
            app.RotSlider.Value=app.Rot;
            app.RotSpinner.Value=app.Rot ;

            Projection(app);
        end

        % Value changed function: RotSpinner
        function RotSpinnerValueChanged(app, event)
            app.Rot = round(app.RotSpinner.Value);
            if app.Rot > app.RotMax
                app.Rot = app.RotMax;
            end
            if app.Rot < -app.RotMax
                app.Rot = -app.RotMax;
            end
            app.RotSlider.Value=app.Rot;
            app.RotSpinner.Value=app.Rot ;

            Projection(app);
        end

        % Button pushed function: ROIset
        function ROIsetButtonPushed(app, event)
            app.Hex=0;
            app.ROIflag=1;
            app.ROIcount=0;
            app.ROI=zeros(6,2);
            app.ROInote.Text=strcat('Specify 1st point of a hexagon');
            Projection(app);
        end

        % Button down function: ProImage
        function ProImageButtonDown(app, event)
            if app.ROIflag==1
                XY=round(app.ProImage.CurrentPoint);
                app.ROIcount=app.ROIcount+1;
                X=XY(1,1);
                Y=XY(1,2);
                app.ROI(app.ROIcount,1)=X;
                app.ROI(app.ROIcount,2)=Y;
                if app.ROIcount==6
                    XX=app.ROI(:,1);
                    YY=app.ROI(:,2);
                    H=polyshape(XX,YY);
                    % V=min(XX);W=min(YY);
                    % width=max(XX)-min(XX);height=max(YY)-min(YY);
                    if (size(H.Vertices,1)==6)&&(H.NumRegions==1)&&(H.NumHoles==0)
                        app.ROInote.Text='A new ROI was speficied';
                        app.Hex=1;
                        app.ROIflag=0;
                        app.ROIcount=0;
                    else
                        app.ROInote.Text='Invalid ROI, start specifying ROI again';
                        app.Hex=0;
                        app.ROIflag=0;
                        app.ROIcount=0;
                    end 
                else
                    app.ROInote.Text=strcat('Specify ',num2str(app.ROIcount+1),'-th point of the hexagon');
                    app.Hex=0;app.ROIflag=1;
                end
                Projection(app);
            end
        end

        % Value changed function: DenSlider
        function DenSliderValueChanged(app, event)
            app.DenSlider.Value=round(app.DenSlider.Value);
            app.DenSpinner.Value=round(app.DenSlider.Value);
            app.Denoise=app.DenSlider.Value;
            Projection(app);
        end

        % Value changed function: DenSpinner
        function DenSpinnerValueChanged(app, event)
            app.DenSlider.Value=round(app.DenSpinner.Value);
            app.DenSpinner.Value=round(app.DenSpinner.Value);
            app.Denoise=app.DenSlider.Value;
            Projection(app);
        end

        % Value changed function: XwgtSlider
        function XwgtSliderValueChanged(app, event)
            app.XwgtSlider.Value=round(app.XwgtSlider.Value,2);
            app.XwgtSpinner.Value=round(app.XwgtSlider.Value,2);
            app.Xwgt=app.XwgtSlider.Value;
            Projection(app);
        end

        % Value changed function: XwgtSpinner
        function XwgtSpinnerValueChanged(app, event)
            app.XwgtSlider.Value=round(app.XwgtSpinner.Value,2);
            app.XwgtSpinner.Value=round(app.XwgtSpinner.Value,2);
            app.Xwgt=app.XwgtSlider.Value;
            Projection(app);
        end

        % Value changed function: DistSlider
        function DistSliderValueChanged(app, event)
            app.DistSlider.Value=round(app.DistSlider.Value);
            app.DistSpinner.Value=round(app.DistSlider.Value);
            app.Dist=app.DistSlider.Value;
            Projection(app);
        end

        % Value changed function: DistSpinner
        function DistSpinnerValueChanged(app, event)
            app.DistSlider.Value=round(app.DistSpinner.Value);
            app.DistSpinner.Value=round(app.DistSpinner.Value);
            app.Dist=app.DistSlider.Value;
            Projection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ZbottomImage = uiaxes(app.UIFigure);
            app.ZbottomImage.XTick = [];
            app.ZbottomImage.YTick = [];
            app.ZbottomImage.Tag = 'ZbottomImage';
            colormap(app.ZbottomImage, 'gray')
            app.ZtopImage = uiaxes(app.UIFigure);
            app.ZtopImage.XTick = [];
            app.ZtopImage.YTick = [];
            app.ZtopImage.ZTick = [];
            app.ZtopImage.Tag = 'ZtopImage';
            colormap(app.ZtopImage, 'gray')
            app.ProImage = uiaxes(app.UIFigure);
            app.ProImage.XTick = [];
            app.ProImage.YTick = [];
            app.ProImage.ZTick = [];
            app.ProImage.ButtonDownFcn = createCallbackFcn(app, @ProImageButtonDown, true);
            app.ProImage.Tag = 'Proimage';
            colormap(app.ProImage, 'gray')
            app.DetImage = uiaxes(app.UIFigure);
            app.DetImage.YDir = 'reverse';
            app.DetImage.XTick = [];
            app.DetImage.YTick = [];
            app.DetImage.Tag = 'Detimage';
            colormap(app.DetImage, 'gray')
            app.LSMfileDropDownLabel = uilabel(app.UIFigure);
            app.LSMfileDropDownLabel.Text = 'LSM file';
            app.LSMfileDropDown = uidropdown(app.UIFigure);
            app.LSMfileDropDown.Items = {'ポップアップ メニュー'};
            app.LSMfileDropDown.ValueChangedFcn = createCallbackFcn(app, @LSMfileDropDownValueChanged, true);
            app.LSMfileDropDown.Value = 'ポップアップ メニュー';
            app.ZtopSlider = uislider(app.UIFigure);
            app.ZtopSlider.Limits = [1 10];
            app.ZtopSlider.MajorTicks = [];
            app.ZtopSlider.ValueChangedFcn = createCallbackFcn(app, @ZtopSliderValueChanged, true);
            app.ZtopSlider.Value = 1;
            app.ZbottomSlider = uislider(app.UIFigure);
            app.ZbottomSlider.Limits = [1 10];
            app.ZbottomSlider.MajorTicks = [];
            app.ZbottomSlider.ValueChangedFcn = createCallbackFcn(app, @ZbottomSliderValueChanged, true);
            app.ZbottomSlider.Value = 10;
            app.ZtopLabel = uilabel(app.UIFigure);
            app.ZtopLabel.Text = 'Z top';
            app.ZbottomLabel = uilabel(app.UIFigure);
            app.ZbottomLabel.Text = 'Z bottom';
            app.ZtopSpinnerLabel = uilabel(app.UIFigure);
            app.ZtopSpinnerLabel.Text = 'Ztop';
            app.ZtopSpinner = uispinner(app.UIFigure);
            app.ZtopSpinner.ValueChangedFcn = createCallbackFcn(app, @ZtopSpinnerValueChanged, true);
            app.ZtopSpinner.Value = 1;
            app.DetLabel = uilabel(app.UIFigure);
            app.DetLabel.Text = 'Detection';
            app.ZbottomSpinnerLabel = uilabel(app.UIFigure);
            app.ZbottomSpinnerLabel.Text = 'Zbottom';
            app.ZbottomSpinner = uispinner(app.UIFigure);
            app.ZbottomSpinner.ValueChangedFcn = createCallbackFcn(app, @ZbottomSpinnerValueChanged, true);
            app.ZbottomSpinner.Value = 1;
            app.CSlider = uislider(app.UIFigure);
            app.CSlider.Limits = [1 3];
            app.CSlider.MajorTicks = [];
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @CSliderValueChanged, true);
            app.CSlider.Value = 1;
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel';
            app.CSpinner = uispinner(app.UIFigure);
            app.CSpinner.Limits = [1 3];
            app.CSpinner.ValueChangedFcn = createCallbackFcn(app, @CSpinnerValueChanged, true);
            app.CSpinner.Value = 1;
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Tag = 'Export';
            app.Export.Text = 'Export';
            app.ProLabel = uilabel(app.UIFigure);
            app.ProLabel.Text = 'Projection';
            app.RotaionLabel = uilabel(app.UIFigure);
            app.RotaionLabel.Text = 'Rotaion';
            app.RotSpinner = uispinner(app.UIFigure);
            app.RotSpinner.Limits = [-30 30];
            app.RotSpinner.ValueChangedFcn = createCallbackFcn(app, @RotSpinnerValueChanged, true);
            app.RotSlider = uislider(app.UIFigure);
            app.RotSlider.Limits = [-30 30];
            app.RotSlider.MajorTicks = [];
            app.RotSlider.ValueChangedFcn = createCallbackFcn(app, @RotSliderValueChanged, true);
            app.ROIset = uibutton(app.UIFigure, 'push');
            app.ROIset.ButtonPushedFcn = createCallbackFcn(app, @ROIsetButtonPushed, true);
            app.ROIset.Text = 'ROIset';
            app.ROInote = uilabel(app.UIFigure);
            app.ROInote.Text = 'Cick to specify a hexagonal ROI';
            app.DenSlider = uislider(app.UIFigure);
            app.DenSlider.Limits = [0 5];
            app.DenSlider.MajorTicks = [];
            app.DenSlider.ValueChangedFcn = createCallbackFcn(app, @DenSliderValueChanged, true);
            app.DenSlider.Value = 2;
            app.RotaionLabel_2 = uilabel(app.UIFigure);
            app.RotaionLabel_2.Tag = 'Denoise';
            app.RotaionLabel_2.Text = 'Denoise';
            app.DenSpinner = uispinner(app.UIFigure);
            app.DenSpinner.Limits = [0 5];
            app.DenSpinner.ValueChangedFcn = createCallbackFcn(app, @DenSpinnerValueChanged, true);
            app.DenSpinner.Value = 2;
            app.XwgtSlider = uislider(app.UIFigure);
            app.XwgtSlider.Limits = [0 1];
            app.XwgtSlider.MajorTicks = [];
            app.XwgtSlider.ValueChangedFcn = createCallbackFcn(app, @XwgtSliderValueChanged, true);
            app.XwgtSlider.Value = 0.5;
            app.RotaionLabel_3 = uilabel(app.UIFigure);
            app.RotaionLabel_3.Tag = 'Denoise';
            app.RotaionLabel_3.Text = 'Xweight';
            app.XwgtSpinner = uispinner(app.UIFigure);
            app.XwgtSpinner.Step = 0.05;
            app.XwgtSpinner.Limits = [0 1];
            app.XwgtSpinner.ValueChangedFcn = createCallbackFcn(app, @XwgtSpinnerValueChanged, true);
            app.XwgtSpinner.Value = 0.5;
            app.DistSlider = uislider(app.UIFigure);
            app.DistSlider.Limits = [5 30];
            app.DistSlider.MajorTicks = [];
            app.DistSlider.ValueChangedFcn = createCallbackFcn(app, @DistSliderValueChanged, true);
            app.DistSlider.Value = 15;
            app.RotaionLabel_4 = uilabel(app.UIFigure);
            app.RotaionLabel_4.Tag = 'Denoise';
            app.RotaionLabel_4.Text = 'Distance';
            app.DistSpinner = uispinner(app.UIFigure);
            app.DistSpinner.Limits = [5 30];
            app.DistSpinner.ValueChangedFcn = createCallbackFcn(app, @DistSpinnerValueChanged, true);
            app.DistSpinner.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = column_arrangement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end